classdef figurePolar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout_main              matlab.ui.container.GridLayout
        GridLayout4                  matlab.ui.container.GridLayout
        GridLayout5                  matlab.ui.container.GridLayout
        rgb_bgButton                 matlab.ui.control.StateButton
        GridLayout2                  matlab.ui.container.GridLayout
        ExportButton                 matlab.ui.control.Button
        ExportallButton              matlab.ui.control.Button
        Panel_exportFormats          matlab.ui.container.Panel
        GridLayout_exportFormat      matlab.ui.container.GridLayout
        rgbImageCheckBox             matlab.ui.control.CheckBox
        dWCheckBox                   matlab.ui.control.CheckBox
        WbarCheckBox                 matlab.ui.control.CheckBox
        txtCheckBox                  matlab.ui.control.CheckBox
        pngCheckBox                  matlab.ui.control.CheckBox
        figCheckBox                  matlab.ui.control.CheckBox
        epsCheckBox                  matlab.ui.control.CheckBox
        GridLayout3                  matlab.ui.container.GridLayout
        maxEditField                 matlab.ui.control.NumericEditField
        maxEditFieldLabel            matlab.ui.control.Label
        minEditField                 matlab.ui.control.NumericEditField
        minLabel                     matlab.ui.control.Label
        nmLabel_2                    matlab.ui.control.Label
        holdCheckBox                 matlab.ui.control.CheckBox
        MeanOPDButton                matlab.ui.control.StateButton
        polarNumberEditField         matlab.ui.control.NumericEditField
        nextPolarButton              matlab.ui.control.Button
        previousPolarButton          matlab.ui.control.Button
        GridLayout_Title             matlab.ui.container.GridLayout
        ImagePolarcontrolLabel       matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        GridLayout                   matlab.ui.container.GridLayout
        EditField                    matlab.ui.control.EditField
        NimEditField                 matlab.ui.control.NumericEditField
        nimEditField                 matlab.ui.control.NumericEditField
        nextButton                   matlab.ui.control.Button
        previousButton               matlab.ui.control.Button
        GridLayout_smoothingParam    matlab.ui.container.GridLayout
        allSmoothingCheckBox         matlab.ui.control.CheckBox
        GridLayout_sliderSmoothing   matlab.ui.container.GridLayout
        smoothingSlider              matlab.ui.control.Slider
        smoothingEditField           matlab.ui.control.NumericEditField
        SmoothingEditFieldLabel      matlab.ui.control.Label
        pxLabel                      matlab.ui.control.Label
        GridLayout_retMaxParam       matlab.ui.container.GridLayout
        allRetMaxCheckBox            matlab.ui.control.CheckBox
        GridLayout_sliderRetMax      matlab.ui.container.GridLayout
        retMaxSlider                 matlab.ui.control.Slider
        retMaxEditField              matlab.ui.control.NumericEditField
        RetardancemaxEditFieldLabel  matlab.ui.control.Label
        nmLabel                      matlab.ui.control.Label
        UIAxes_Theta                 matlab.ui.control.UIAxes
        UIAxes_Retardance            matlab.ui.control.UIAxes
        UIAxes_OPD                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        pIm0 % Original, unmodfied ImagePolar object
        pIm % copy of the current ImagePolar object
        cb_OPD % Theta Axes colorbar
        cb_Theta % Theta Axes colorbar
        cb_Ret   % Retardance Axes colorbar
        Nim
        IM  % (smoothed) ImageQLSI
        smfun = @(x) x % Smoothing function, either imgaussfilt or Id
    end

    properties(Dependent)
        nim
    end
    
    methods (Access = private)
        
        function updateImages(app)

            if app.MeanOPDButton.Value
                numim = app.polarNumberEditField.Value;
                %imagesc(app.UIAxes_OPD, app.pIm.IM(numim).OPDnm);           
                app.UIAxes_OPD.findobj('Type','image').CData = app.pIm.IM(numim).OPDnm;
            else
                imagesc(app.UIAxes_OPD, 1e9*app.pIm.Wbar);
            end

            if ~app.holdCheckBox.Value
                app.minEditField.Value = min(app.UIAxes_OPD.findobj('Type','image').CData(:));
                app.maxEditField.Value = max(app.UIAxes_OPD.findobj('Type','image').CData(:));
            end
            app.update_climOPD()

            imagesc(app.UIAxes_Retardance, app.pIm.retardance*1e9);
            clim(app.UIAxes_Retardance, [0 app.retMaxEditField.Value])
            imagesc(app.UIAxes_Theta, app.pIm.rgbImage);
            xlim(app.UIAxes_OPD,[1 app.pIm.Nx]);
            ylim(app.UIAxes_OPD,[1 app.pIm.Ny]);
            xlim(app.UIAxes_Retardance,[1 app.pIm.Nx]);
            ylim(app.UIAxes_Retardance,[1 app.pIm.Ny]);
            xlim(app.UIAxes_Theta,[1 app.pIm.Nx]);
            ylim(app.UIAxes_Theta,[1 app.pIm.Ny]);
            linkaxes([app.UIAxes_OPD, app.UIAxes_Retardance, app.UIAxes_Theta])

            sb = Scalebar(20e-6, app.pIm.IM(1).pxSize);
            sb.insert(app.UIAxes_OPD)

            if strcmp(app.pIm.rgb_bg0,'black')
                app.rgb_bgButton.Value = 1;
                app.rgb_bgButton.BackgroundColor = [0 0 0];
                app.rgb_bgButton.FontColor = [1 1 1];
            else
                app.rgb_bgButton.Value = 0;
                app.rgb_bgButton.BackgroundColor = [1 1 1];
                app.rgb_bgButton.FontColor = [0 0 0];
            end


            drawnow
        end

        
        function updateImagePolarParameters(app)
            app.pIm.set_retMax(app.retMaxEditField.Value)

        end

        
        function enablePolarButtons(app, val)
            if val
                app.nextPolarButton.Enable      = "on";
                app.previousPolarButton.Enable  = "on";
                app.polarNumberEditField.Enable = "on";
            else
                app.nextPolarButton.Enable      = "off";
                app.previousPolarButton.Enable  = "off";
                app.polarNumberEditField.Enable = "off";
            end
        end
        
        function update_climOPD(app)
            if app.minEditField.Value ~= app.maxEditField.Value % in case of uniform image
                clim(app.UIAxes_OPD, [app.minEditField.Value, app.maxEditField.Value])
            end
        end
    end

    methods

        function val = get.nim(app)
            val = app.nimEditField.Value; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, polarImages)
            app.pIm0 = polarImages;
            app.pIm = app.pIm0(1);
            app.retMaxEditField.Value = app.pIm0(1).retardanceMax*1e9;
            app.retMaxSlider.Value    = app.pIm0(1).retardanceMax*1e9;
            
            % Setting the Axes
            app.cb_OPD   = colorbar(app.UIAxes_OPD,  'Location','southoutside','Color',[1 1 1]);
            app.cb_Ret   = colorbar(app.UIAxes_Retardance,  'Location','southoutside','Color',[1 1 1]);
            app.cb_Theta = colorbar(app.UIAxes_Theta,'Location','southoutside','Color',[1 1 1]);
            app.cb_Theta.Ticks = [0,0.25,0.5,0.75,1];
            app.cb_Theta.TickLabels = {"0","\pi/4","\pi/2","3\pi/4","\pi"};
            app.cb_Theta.FontSize = 16;

            colormap(app.UIAxes_OPD,Sepia);
            colormap(app.UIAxes_Retardance,"gray");
            colormap(app.UIAxes_Theta,"hsv");
            
            app.UIAxes_OPD.XColor = [1 1 1];
            app.UIAxes_OPD.YColor = [1 1 1];
            app.UIAxes_OPD.Title.Color = [1 1 1];
            app.UIAxes_Retardance.XColor = [1 1 1];
            app.UIAxes_Retardance.YColor = [1 1 1];
            app.UIAxes_Retardance.Title.Color = [1 1 1];
            app.UIAxes_Theta.XColor = [1 1 1];
            app.UIAxes_Theta.YColor = [1 1 1];
            app.UIAxes_Theta.Title.Color = [1 1 1];

            set(app.UIAxes_OPD,'DataAspectRatio',[1 1 1])
            set(app.UIAxes_Retardance,'DataAspectRatio',[1 1 1])
            set(app.UIAxes_Theta,     'DataAspectRatio',[1 1 1])

            drawnow

            app.Nim = numel(app.pIm0);
            app.NimEditField.Value = app.Nim;

            app.updateImages()
        end

        % Value changed function: retMaxEditField, retMaxSlider
        function retMaxValueChanged(app, event)
            value = event.Source.Value;
            if value < 1e-3 % value in [m]
                value = value*1e9; % conversion in nm
            end
            value = round(value);
            app.retMaxSlider.Value    = value;
            app.retMaxEditField.Value = value;

            app.pIm.set_retMax(value)
            if app.allRetMaxCheckBox.Value
                app.pIm0.set_retMax(value)
            else
                app.pIm0(app.nim).set_retMax(value)
            end            
            app.updateImages()
            
        end

        % Value changed function: smoothingEditField, smoothingSlider
        function smoothingValueChanged(app, event)
            value = event.Source.Value;
            app.smoothingSlider.Value = value;
            app.smoothingEditField.Value = value;

            app.pIm.set_smoothing(value)
            if app.allSmoothingCheckBox.Value
                app.pIm0.set_smoothing(value)
            else
                app.pIm0(app.nim).set_smoothing(value)
            end
            app.updateImages()

        end

        % Callback function: nextButton, nimEditField, previousButton
        function arrowButtonPushed(app, event)
            switch event.Source.Tag
                case 'next'
                    step = 1;
                case 'previous'
                    step = -1;
                otherwise  % don't push any arrow, but enter an image number in the nimEditField
                    step = 0;
            end

            app.nimEditField.Value = mod(app.nim + step - 1, app.Nim) + 1;
            app.pIm = Methods.duplicate(app.pIm0(app.nim)); % copy of the ImagePolar object
            app.retMaxEditField.Value = app.pIm.retardanceMax*1e9; % copy the actual retMax value of the slide in the newly displayed image
            app.smoothingEditField.Value = app.pIm.smoothing; % copy the actual retMax value of the slide in the newly displayed image
            app.retMaxSlider.Value = app.pIm.retardanceMax*1e9; % copy the actual retMax value of the slide in the newly displayed image
            app.smoothingSlider.Value = app.pIm.smoothing; % copy the actual retMax value of the slide in the newly displayed image
            app.updateImages()
        end

        % Button pushed function: ExportButton, ExportallButton
        function ExportButtonPushed(app, event)
            event.Source.Enable = "off";
            drawnow
            tickList0   = app.Panel_exportFormats.Children.Children;
            formatList0 = tickList0(1:4);
            typeList0   = tickList0(5:7);
            typeValid   = [typeList0.Value];
            formatValid = [formatList0.Value];
            folder = "export";
            mkdir(folder)
            switch event.Source.Tag
                case 'all'
                    app.pIm0.export(folder, "formats", string({formatList0(formatValid).Text}), "types", string({typeList0(typeValid).Text}))
                otherwise
                    app.pIm.export( folder, "formats", string({formatList0(formatValid).Text}), "types", string({typeList0(typeValid).Text}))
            end
            event.Source.Enable = "on";
        end

        % Value changed function: MeanOPDButton
        function MeanOPDButtonValueChanged(app, event)
            value = app.MeanOPDButton.Value;
            if value
                app.MeanOPDButton.Text = '4 polar images';
                app.enablePolarButtons(1)
                app.MeanOPDButton.BackgroundColor = [0.4 0.3 0.1];
                app.MeanOPDButton.FontColor = [1 1 1];
            else
                app.MeanOPDButton.Text = 'Mean polar images';
                app.enablePolarButtons(0)
                app.MeanOPDButton.BackgroundColor = [1 1 1];
                app.MeanOPDButton.FontColor = [0 0 0];
            end
            app.updateImages()
        end

        % Button pushed function: nextPolarButton, previousPolarButton
        function navigatePolarButtonPushed(app, event)
            imNum = app.polarNumberEditField.Value;
            switch event.Source.Tag
                case 'next'
                    imNum = mod(imNum, 4) + 1;
                case 'previous'
                    imNum = mod(imNum - 2 , 4) + 1;

            end
            app.polarNumberEditField.Value = imNum;
            app.updateImages()
        end

        % Value changed function: maxEditField, minEditField
        function climEditFieldValueChanged(app, event)
            app.update_climOPD()
        end

        % Value changed function: rgb_bgButton
        function rgb_bgButtonValueChanged(app, event)
            value = app.rgb_bgButton.Value;
            if value
                app.rgb_bgButton.BackgroundColor = [0 0 0];
                app.rgb_bgButton.FontColor = [1 1 1];
                app.pIm0.rgb_bg('black');
                app.pIm.rgb_bg('black');
            else
                app.rgb_bgButton.BackgroundColor = [1 1 1];
                app.rgb_bgButton.FontColor = [0 0 0];
                app.pIm0.rgb_bg('white');
                app.pIm.rgb_bg('white');
            end
            app.updateImages()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.UIAxes_OPD = uiaxes(app.GridLayout_main);
            title(app.UIAxes_OPD, 'OPD [nm]')
            app.UIAxes_Retardance = uiaxes(app.GridLayout_main);
            title(app.UIAxes_Retardance, 'Retardance [nm]')
            app.UIAxes_Theta = uiaxes(app.GridLayout_main);
            title(app.UIAxes_Theta, 'Theta')
            app.GridLayout_retMaxParam = uigridlayout(app.GridLayout_main);
            app.nmLabel = uilabel(app.GridLayout_retMaxParam);
            app.nmLabel.Text = 'nm';
            app.RetardancemaxEditFieldLabel = uilabel(app.GridLayout_retMaxParam);
            app.RetardancemaxEditFieldLabel.Text = 'Retardance max';
            app.retMaxEditField = uieditfield(app.GridLayout_retMaxParam, 'numeric');
            app.retMaxEditField.Limits = [0 200];
            app.retMaxEditField.ValueDisplayFormat = '%11.1f';
            app.retMaxEditField.ValueChangedFcn = createCallbackFcn(app, @retMaxValueChanged, true);
            app.retMaxEditField.Value = 20;
            app.GridLayout_sliderRetMax = uigridlayout(app.GridLayout_retMaxParam);
            app.retMaxSlider = uislider(app.GridLayout_sliderRetMax);
            app.retMaxSlider.Limits = [0 200];
            app.retMaxSlider.ValueChangedFcn = createCallbackFcn(app, @retMaxValueChanged, true);
            app.retMaxSlider.Value = 20;
            app.allRetMaxCheckBox = uicheckbox(app.GridLayout_retMaxParam);
            app.allRetMaxCheckBox.Text = 'all';
            app.GridLayout_smoothingParam = uigridlayout(app.GridLayout_main);
            app.pxLabel = uilabel(app.GridLayout_smoothingParam);
            app.pxLabel.Text = 'px';
            app.SmoothingEditFieldLabel = uilabel(app.GridLayout_smoothingParam);
            app.SmoothingEditFieldLabel.Text = 'Smoothing';
            app.smoothingEditField = uieditfield(app.GridLayout_smoothingParam, 'numeric');
            app.smoothingEditField.Limits = [0 500];
            app.smoothingEditField.RoundFractionalValues = 'on';
            app.smoothingEditField.ValueDisplayFormat = '%11.0f';
            app.smoothingEditField.ValueChangedFcn = createCallbackFcn(app, @smoothingValueChanged, true);
            app.GridLayout_sliderSmoothing = uigridlayout(app.GridLayout_smoothingParam);
            app.smoothingSlider = uislider(app.GridLayout_sliderSmoothing);
            app.smoothingSlider.Limits = [0 500];
            app.smoothingSlider.ValueChangedFcn = createCallbackFcn(app, @smoothingValueChanged, true);
            app.allSmoothingCheckBox = uicheckbox(app.GridLayout_smoothingParam);
            app.allSmoothingCheckBox.Text = 'all';
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.previousButton = uibutton(app.GridLayout, 'push');
            app.previousButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.previousButton.Tag = 'previous';
            app.previousButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.previousButton.Text = '';
            app.nextButton = uibutton(app.GridLayout, 'push');
            app.nextButton.ButtonPushedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nextButton.Tag = 'next';
            app.nextButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.nextButton.Text = '';
            app.nimEditField = uieditfield(app.GridLayout, 'numeric');
            app.nimEditField.Limits = [1 Inf];
            app.nimEditField.ValueChangedFcn = createCallbackFcn(app, @arrowButtonPushed, true);
            app.nimEditField.Value = 1;
            app.NimEditField = uieditfield(app.GridLayout, 'numeric');
            app.NimEditField.Limits = [1 Inf];
            app.NimEditField.Editable = 'off';
            app.NimEditField.Value = 1;
            app.EditField = uieditfield(app.GridLayout, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = '/';
            app.GridLayout_Title = uigridlayout(app.GridLayout_main);
            app.Image = uiimage(app.GridLayout_Title);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo_IA_3.png');
            app.ImagePolarcontrolLabel = uilabel(app.GridLayout_Title);
            app.ImagePolarcontrolLabel.Text = {'I m a g e P o l a r'; 'c o n t r o l'};
            app.GridLayout3 = uigridlayout(app.GridLayout_main);
            app.previousPolarButton = uibutton(app.GridLayout3, 'push');
            app.previousPolarButton.ButtonPushedFcn = createCallbackFcn(app, @navigatePolarButtonPushed, true);
            app.previousPolarButton.Tag = 'previous';
            app.previousPolarButton.Icon = fullfile(pathToMLAPP, 'leftArrow.svg');
            app.previousPolarButton.Enable = 'off';
            app.previousPolarButton.Text = '';
            app.nextPolarButton = uibutton(app.GridLayout3, 'push');
            app.nextPolarButton.ButtonPushedFcn = createCallbackFcn(app, @navigatePolarButtonPushed, true);
            app.nextPolarButton.Tag = 'next';
            app.nextPolarButton.Icon = fullfile(pathToMLAPP, 'rightArrow.svg');
            app.nextPolarButton.Enable = 'off';
            app.nextPolarButton.Text = '';
            app.polarNumberEditField = uieditfield(app.GridLayout3, 'numeric');
            app.polarNumberEditField.Limits = [1 4];
            app.polarNumberEditField.RoundFractionalValues = 'on';
            app.polarNumberEditField.ValueDisplayFormat = '%1.0d';
            app.polarNumberEditField.Editable = 'off';
            app.polarNumberEditField.Enable = 'off';
            app.polarNumberEditField.Value = 1;
            app.MeanOPDButton = uibutton(app.GridLayout3, 'state');
            app.MeanOPDButton.ValueChangedFcn = createCallbackFcn(app, @MeanOPDButtonValueChanged, true);
            app.MeanOPDButton.Text = 'Mean OPD';
            app.holdCheckBox = uicheckbox(app.GridLayout3);
            app.holdCheckBox.Text = 'hold';
            app.nmLabel_2 = uilabel(app.GridLayout3);
            app.nmLabel_2.Text = 'nm';
            app.minLabel = uilabel(app.GridLayout3);
            app.minLabel.Text = 'min';
            app.minEditField = uieditfield(app.GridLayout3, 'numeric');
            app.minEditField.RoundFractionalValues = 'on';
            app.minEditField.ValueDisplayFormat = '%11.0f';
            app.minEditField.ValueChangedFcn = createCallbackFcn(app, @climEditFieldValueChanged, true);
            app.maxEditFieldLabel = uilabel(app.GridLayout3);
            app.maxEditFieldLabel.Text = 'max';
            app.maxEditField = uieditfield(app.GridLayout3, 'numeric');
            app.maxEditField.RoundFractionalValues = 'on';
            app.maxEditField.ValueDisplayFormat = '%11.0f';
            app.maxEditField.ValueChangedFcn = createCallbackFcn(app, @climEditFieldValueChanged, true);
            app.GridLayout4 = uigridlayout(app.GridLayout_main);
            app.GridLayout2 = uigridlayout(app.GridLayout4);
            app.Panel_exportFormats = uipanel(app.GridLayout2);
            app.GridLayout_exportFormat = uigridlayout(app.Panel_exportFormats);
            app.epsCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.epsCheckBox.Text = 'eps';
            app.figCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.figCheckBox.Text = 'fig';
            app.pngCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.pngCheckBox.Text = 'png';
            app.txtCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.txtCheckBox.Text = 'txt';
            app.WbarCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.WbarCheckBox.Text = 'Wbar';
            app.dWCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.dWCheckBox.Text = 'dW';
            app.rgbImageCheckBox = uicheckbox(app.GridLayout_exportFormat);
            app.rgbImageCheckBox.Text = 'rgbImage';
            app.ExportallButton = uibutton(app.GridLayout2, 'push');
            app.ExportallButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportallButton.Tag = 'all';
            app.ExportallButton.Text = 'Export all';
            app.ExportButton = uibutton(app.GridLayout2, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.rgb_bgButton = uibutton(app.GridLayout5, 'state');
            app.rgb_bgButton.ValueChangedFcn = createCallbackFcn(app, @rgb_bgButtonValueChanged, true);
            app.rgb_bgButton.Text = 'rgb background';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = figurePolar(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end