classdef segmentationMaskGenApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        DoneButton              matlab.ui.control.Button
        TextArea                matlab.ui.control.TextArea
        Grid_Cut                matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        invertCheckBox          matlab.ui.control.CheckBox
        CutLabel                matlab.ui.control.Label
        cutEditField            matlab.ui.control.NumericEditField
        cutSlider               matlab.ui.control.Slider
        Grid_Smoothing          matlab.ui.container.GridLayout
        smoothingEditField      matlab.ui.control.NumericEditField
        smoothingSlider         matlab.ui.control.Slider
        SmoothingLabel          matlab.ui.control.Label
        Grid_GradThresh         matlab.ui.container.GridLayout
        gradThreshEditField     matlab.ui.control.NumericEditField
        gradThreshSlider        matlab.ui.control.Slider
        GradientThresholdLabel  matlab.ui.control.Label
        UIAxes_imgCut           matlab.ui.control.UIAxes
        UIAxes_imgSmoothing     matlab.ui.control.UIAxes
        UIAxes_imgGradThresh    matlab.ui.control.UIAxes
        UIAxes_img0             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        img0 % raw image
        mask1 logical % mask of the GradThreshold
        mask2 double % mask aftersmoothing
        Dimgx % gradient of the image along x
        Dimgy % gradient of the image along y
    end

    properties(Access = public)
        Finished = false
        maskFinal logical % mask after final threshold
    end

    properties(Dependent)
        gradThresh % Gradient threshold
        smoothing
        cut
        invert
    end
    
    methods (Access = private)
        
        function computeMasks(app)

            [app.maskFinal, app.mask1, app.mask2] = computeSegmentationMasks(app.Dimgx, app.Dimgy, ...
                app.gradThresh, app.smoothing, app.cutEditField.Value, app.invert);

        end
    
        
        function updateImages(app)
            app.UIAxes_imgGradThresh.Children.CData = app.mask1;
            app.UIAxes_imgSmoothing.Children.CData       = app.mask2;
            app.UIAxes_imgCut.Children.CData = app.maskFinal;

        end
    end

    methods

        function val = get.gradThresh(app)
            val = app.gradThreshEditField.Value*1e-9;
        end

        function val = get.cut(app)
            val = app.cutEditField.Value;
        end

        function val = get.smoothing(app)
            val = app.smoothingEditField.Value;
        end

        function val = get.invert(app)
            val = app.invertCheckBox.Value;
        end

        function set.gradThresh(app, val)
            if val<1e-3
                app.gradThreshEditField.Value = val*1e9;
                app.gradThreshSlider.Value = val*1e9;
            else
                app.gradThreshEditField.Value = val;
                app.gradThreshSlider.Value = val;
            end
        end

        function set.cut(app, val)
                app.cutEditField.Value = val;
                app.cutSlider.Value = val;
        end

        function set.smoothing(app, val)
                app.smoothingEditField.Value = val;
                app.smoothingSlider.Value = val;
        end

        function set.invert(app, val)
            arguments
                app
                val logical
            end
                app.invertCheckBox.Value = val;
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img0, opt)
            arguments
                app
                img0 = rand(100,70)
                opt.gradThresh = 2
                opt.smoothing  = 5
                opt.cut        = 0.1
                opt.invert     = false
            end


            app.gradThresh = opt.gradThresh;
            app.smoothing  = opt.smoothing;
            app.cut        = opt.cut;
            app.invert     = opt.invert;

            if isa(img0,'ImageQLSI')
                app.img0  = img0.OPD;
                app.Dimgx = img0.DWx;
                app.Dimgy = img0.DWy;
            elseif isnumeric(img0)
                app.img0  = img0;
                [app.Dimgx, app.Dimgy] = imgradientxy(img0);
            else
                error('Not a proper input type. Must be ImageQLSI or numeric')
            end

            %app.UIAxes_img0.Visible = 'off';
            app.computeMasks()

            % display img0 image
            imagesc(app.UIAxes_img0,app.img0)
            app.UIAxes_img0.XLim = [1,size(app.img0,2)];
            app.UIAxes_img0.YLim = [1,size(app.img0,1)];
            app.UIAxes_img0.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_img0,phase1024)
            colorbar(app.UIAxes_img0,'location','southoutside')
            
            % display GradientThreshold image
            imagesc(app.UIAxes_imgGradThresh, app.mask1)
            app.UIAxes_imgGradThresh.XLim = [1,size(app.img0,2)];
            app.UIAxes_imgGradThresh.YLim = [1,size(app.img0,1)];
            app.UIAxes_imgGradThresh.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_imgGradThresh,"gray")
            colorbar(app.UIAxes_imgGradThresh,'location','southoutside')

            % display smoothed image
            imagesc(app.UIAxes_imgSmoothing, app.mask2)
            app.UIAxes_imgSmoothing.XLim = [1,size(app.img0,2)];
            app.UIAxes_imgSmoothing.YLim = [1,size(app.img0,1)];
            app.UIAxes_imgSmoothing.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_imgSmoothing,"gray")
            colorbar(app.UIAxes_imgSmoothing,'location','southoutside')

            % display Final image
            imagesc(app.UIAxes_imgCut, app.maskFinal)
            app.UIAxes_imgCut.XLim = [1,size(app.img0,2)];
            app.UIAxes_imgCut.YLim = [1,size(app.img0,1)];
            app.UIAxes_imgCut.DataAspectRatio = [1 1 1];
            colormap(app.UIAxes_imgCut,"gray")
            colorbar(app.UIAxes_imgCut,'location','southoutside')


        end

        % Value changed function: cutEditField, cutSlider, 
        % ...and 5 other components
        function paramValueChanged(app, event)
            tag = event.Source.Tag;
            if ~strcmp(tag,'invert')
                app.([tag 'EditField']).Value = event.Source.Value;
                app.([tag 'Slider']).Value    = event.Source.Value;
            end
            app.computeMasks()
            app.updateImages()

        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            app.Finished = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes_img0 = uiaxes(app.GridLayout);
            title(app.UIAxes_img0, 'Image')
            app.UIAxes_img0.XTick = [];
            app.UIAxes_img0.YTick = [];
            app.UIAxes_img0.ZTick = [];
            app.UIAxes_imgGradThresh = uiaxes(app.GridLayout);
            title(app.UIAxes_imgGradThresh, 'Mask')
            app.UIAxes_imgGradThresh.XTick = [];
            app.UIAxes_imgGradThresh.YTick = [];
            app.UIAxes_imgSmoothing = uiaxes(app.GridLayout);
            title(app.UIAxes_imgSmoothing, 'Mask')
            app.UIAxes_imgSmoothing.XTick = [];
            app.UIAxes_imgSmoothing.YTick = [];
            app.UIAxes_imgCut = uiaxes(app.GridLayout);
            title(app.UIAxes_imgCut, 'Final mask')
            app.UIAxes_imgCut.XTick = [];
            app.UIAxes_imgCut.YTick = [];
            app.Grid_GradThresh = uigridlayout(app.GridLayout);
            app.GradientThresholdLabel = uilabel(app.Grid_GradThresh);
            app.GradientThresholdLabel.Text = 'Gradient threshold';
            app.gradThreshSlider = uislider(app.Grid_GradThresh);
            app.gradThreshSlider.Limits = [0 2];
            app.gradThreshSlider.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.gradThreshSlider.Tag = 'gradThresh';
            app.gradThreshSlider.Value = 2;
            app.gradThreshEditField = uieditfield(app.Grid_GradThresh, 'numeric');
            app.gradThreshEditField.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.gradThreshEditField.Tag = 'gradThresh';
            app.gradThreshEditField.Value = 2;
            app.Grid_Smoothing = uigridlayout(app.GridLayout);
            app.SmoothingLabel = uilabel(app.Grid_Smoothing);
            app.SmoothingLabel.Text = 'Smoothing';
            app.smoothingSlider = uislider(app.Grid_Smoothing);
            app.smoothingSlider.Limits = [0 20];
            app.smoothingSlider.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.smoothingSlider.Tag = 'smoothing';
            app.smoothingSlider.Value = 5;
            app.smoothingEditField = uieditfield(app.Grid_Smoothing, 'numeric');
            app.smoothingEditField.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.smoothingEditField.Tag = 'smoothing';
            app.smoothingEditField.Value = 5;
            app.Grid_Cut = uigridlayout(app.GridLayout);
            app.cutSlider = uislider(app.Grid_Cut);
            app.cutSlider.Limits = [0 1];
            app.cutSlider.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.cutSlider.Tag = 'cut';
            app.cutSlider.Value = 0.1;
            app.cutEditField = uieditfield(app.Grid_Cut, 'numeric');
            app.cutEditField.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.cutEditField.Tag = 'cut';
            app.cutEditField.Value = 0.1;
            app.GridLayout2 = uigridlayout(app.Grid_Cut);
            app.CutLabel = uilabel(app.GridLayout2);
            app.CutLabel.Text = 'Cut';
            app.invertCheckBox = uicheckbox(app.GridLayout2);
            app.invertCheckBox.ValueChangedFcn = createCallbackFcn(app, @paramValueChanged, true);
            app.invertCheckBox.Tag = 'invert';
            app.invertCheckBox.Text = 'invert';
            app.TextArea = uitextarea(app.GridLayout);
            app.TextArea.Value = {'Segmentation'; 'Mask'; 'Generator'};
            app.DoneButton = uibutton(app.GridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = segmentationMaskGenApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end