classdef LIVE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout_main            matlab.ui.container.GridLayout
        GridLayout2                matlab.ui.container.GridLayout
        GridLayout_frameRate       matlab.ui.container.GridLayout
        HzEditField                matlab.ui.control.NumericEditField
        HzEditFieldLabel           matlab.ui.control.Label
        GridLayout_panelButtons    matlab.ui.container.GridLayout
        PhaseLABButton             matlab.ui.control.StateButton
        controlButton              matlab.ui.control.StateButton
        zcontrolButton             matlab.ui.control.StateButton
        setupButton                matlab.ui.control.StateButton
        GridLayout_windowsButtons  matlab.ui.container.GridLayout
        closeallButton             matlab.ui.control.Button
        canals4Button              matlab.ui.control.StateButton
        canals2Button              matlab.ui.control.StateButton
        RytovButton                matlab.ui.control.StateButton
        PDCMButton                 matlab.ui.control.StateButton
        massButton                 matlab.ui.control.StateButton
        dWButton                   matlab.ui.control.StateButton
        WButton                    matlab.ui.control.StateButton
        TButton                    matlab.ui.control.StateButton
        rawButton                  matlab.ui.control.StateButton
        GridLayout                 matlab.ui.container.GridLayout
        GridLayout_Imaging_5       matlab.ui.container.GridLayout
        GridLayout_tools           matlab.ui.container.GridLayout
        HR_Button                  matlab.ui.control.StateButton
        cropButton                 matlab.ui.control.Button
        radialAvgButton            matlab.ui.control.Button
        pxValueButton              matlab.ui.control.Button
        areaButton                 matlab.ui.control.Button
        pxLabel                    matlab.ui.control.Label
        imageDimensionsDropDown    matlab.ui.control.DropDown
        msLabel                    matlab.ui.control.Label
        expTimeEditField           matlab.ui.control.NumericEditField
        tauLabel                   matlab.ui.control.Label
        flattenButton              matlab.ui.control.StateButton
        cancelSymmetryButton       matlab.ui.control.Button
        rot90Button                matlab.ui.control.Button
        HmirrorButton              matlab.ui.control.Button
        VmirrorButton              matlab.ui.control.Button
        GridLayout_Imaging_4       matlab.ui.container.GridLayout
        GridLayout3                matlab.ui.container.GridLayout
        timeLapseFileButton        matlab.ui.control.Button
        loadFileButton             matlab.ui.control.Button
        GridLayout_savePrefix      matlab.ui.container.GridLayout
        saveFolderEditField        matlab.ui.control.EditField
        saveFolderButton           matlab.ui.control.Button
        savePrefixEditField        matlab.ui.control.EditField
        autosaveCheckBox           matlab.ui.control.CheckBox
        savedFileNameEditField     matlab.ui.control.EditField
        saveButton                 matlab.ui.control.Button
        GridLayout_Imaging_3       matlab.ui.container.GridLayout
        liveAvgEditField           matlab.ui.control.NumericEditField
        refAvgEditField            matlab.ui.control.NumericEditField
        burstCheckBox              matlab.ui.control.CheckBox
        snapAvgEditField           matlab.ui.control.NumericEditField
        SnapButton                 matlab.ui.control.Button
        RefButton                  matlab.ui.control.Button
        LiveButton                 matlab.ui.control.StateButton
        Image                      matlab.ui.control.Image
        GridLayout_setup           matlab.ui.container.GridLayout
        Lamp                       matlab.ui.control.Button
        mmLabel                    matlab.ui.control.Label
        CGdistanceEditField        matlab.ui.control.NumericEditField
        nmLabel                    matlab.ui.control.Label
        RelayLensEditField         matlab.ui.control.NumericEditField
        RelayLensEditFieldLabel    matlab.ui.control.Label
        CrossGratingDropDown       matlab.ui.control.DropDown
        CrossGratingDropDownLabel  matlab.ui.control.Label
        cameraDropDown             matlab.ui.control.DropDown
        cameraDropDownLabel        matlab.ui.control.Label
        microscopeTLDropDown       matlab.ui.control.DropDown
        microscopeTLDropDownLabel  matlab.ui.control.Label
        objBrandDropDown           matlab.ui.control.DropDown
        lambdaEditField            matlab.ui.control.NumericEditField
        lambdaLabel                matlab.ui.control.Label
        MobjEditField              matlab.ui.control.NumericEditField
        MobjEditFieldLabel         matlab.ui.control.Label
    end


    properties (SetAccess = private)
        Itf Interfero % current interferogram
        IM  ImageMethods % current ImageQLSI object
        Ref Interfero % current reference interferogram
        MI  Microscope % current microscope.   Same handle as Itf.Microscope & Ref.Microscope & IM.Microscope
        IL  Illumination % current illumination. Same handle as IM.Illumination
    end

    properties (Dependent)
        % The properties are associated with set and get functions.
        % Here is how other functions interact with this class,
        % not by modifying Itf and IM themselves.
        % The caution protects, e.g., the Microscope and Illumination properties
        % of the Itf and IM properties.

        Itf0  % current interferogram image
        Ref0  % current interferogram image
        W0    % current wavefront image
        T0    % current intensity image
        DWx0  % current x-gradient image
        DWy0  % current y-gradient image

    end

    properties (Dependent)
        Cam % CameraExp object
        Nx
        Ny
    end

    properties (SetAccess = private)
        liveOn = 0   % = 1 when live in running

        hfigList % struct containing the list of the handles of the open figures
        haxList  % struct containing the list of the handles of the axes in figures
        himList  % struct containing the list of the handles of the displayed images

        roi images.roi.Rectangle = images.roi.Rectangle.empty(1,0)  % Region of interest to be cropped before processing and display

        %roi_x (1,:) double = double.empty(1,0)  % roi to be computed when peephole activated
        %roi_y (1,:) double = double.empty(1,0)  %
    end

    properties(Access = private)

        nPos = 0 % position of the last opened figure, 0, 1 or 2 (1st, 2nd and 3rd lateral third of the screen)

        D4Transform char % Symmetry transormation of the image, char array composed of H, V, and R letters. e.g.,'HRV' 

        cameraType {mustBeMember(cameraType,{'','Andor','Hamamatsu','pixelink','PCO'})} = ''

        %  color of the toggle buttons
        colorButtonOn  = [0.5, 0.5, 0.5] % dark
        colorButtonOff = [0.94, 0.94, 0.94] % bright

        lampColorOn = [0.60    0.77    0.60];   % green
        lampColorOff = [0.9 0.9 0.9];  % light gray
        lampColorWait = [0.7 0.5,0.3]; % orange
        lampColorError = [0.8    0.40    0.40];   % red
        lampColorRunning = [0.7 0.5,0.3]; % orange

        simuColorOn = [0.60   0.77    0.60];   % green
        simuColorOff = [0.15 0.15 0.15] % dark gray
    end

    properties(Dependent)
        NavgLive % returns the liveAvgEditField value
        NavgRef  % returns the refAvgEditField value
        NavgSnap % returns the sanoAvgEditField value

        expTime % exposure time

        status_hfigList % list of logicalv alues indicating which image windows are open

    end
    
    methods (Access = private)

        function changeButtonColor(app,btn)
            if btn.Value == 1
                % Activated mode
                btn.BackgroundColor = app.colorButtonOn;
                btn.FontColor = [1 1 1];  % White
            else
                % Desactivated mode
                btn.BackgroundColor = app.colorButtonOff ;
                btn.FontColor = [0 0 0];  % Black
            end
        end
        
        function startCamera(app)
            app.lampState('Wait')
            app.Cam.setupSDK()
            rc = app.Cam.startCamera();
            if rc~=0
                disp('error in the camera startup')
                app.lampState('Error');
            else
                app.lampState('On');
            end

        end

        function stopCamera(app)

            app.Lamp.BackgroundColor  = app.lampColorWait;

            switch app.cameraType
                case 'None'
                    app.Lamp.BackgroundColor = [0.2 0.8,0.3];
                    return
                case 'Andor'
                    [rc] = AT_Close(app.Cam.hndl);
                    AT_CheckWarning2(rc,app.TextArea);
                    [rc] = AT_FinaliseLibrary();
                    AT_CheckWarning2(rc,app.TextArea);
                case 'Hamamatsu'

                case 'pixelink'

                case 'PCO'
            end

            app.Lamp.BackgroundColor = app.lampColorOff;

        end

        function enableCameraParameters(app,value)
            % enable or not the parameters in relation with a RL
            arguments
                app
                value %{mustBeMember(value,{"on","off"})}
            end
            app.CrossGratingDropDown.Enable = value;
            app.RelayLensEditField.Enable = value;
            app.CrossGratingDropDownLabel.Enable = value;
            app.RelayLensEditFieldLabel.Enable = value;
            app.CGdistanceEditField.Enable = value;
                        
        end

        function MI2panel(app)
            % update the app.MI with what is written in the parameters panel

            app.MobjEditField.Value = app.MI.Objective.Mobj;   % update Mobj field
            app.cameraDropDown.Value = app.MI.CGcam.fileName;
            app.microscopeTLDropDown.Value = string(app.MI.f_TL) ;
            if isempty(app.MI.CGcam.RL)
                 app.RelayLensEditField.Value = [];
            else
                app.RelayLensEditField.Value = app.MI.CGcam.RL.zoom;
            end
            app.CrossGratingDropDown.Value = app.MI.CGcam.CG.name;

        end

        function panel2MI(app)
            % update the app.MI with what is written in the parameters panel
            % except the camera (update the camera with app.updateCamera() )


            app.MI.Objective = Objective(app.MobjEditField.Value,0.7,app.objBrandDropDown.Value);
            app.MI.f_TL = app.microscopeTLDropDown.Value; 
            app.MI.CGcam.setDistance(app.CGdistanceEditField.Value*1e-3); 
            if isempty(app.RelayLensEditField.Value)
                app.MI.CGcam.RL = RelayLens.empty();
            else
                app.MI.CGcam.RL = RelayLens(app.RelayLensEditField.Value);
            end
            if strcmp(app.CrossGratingDropDown.Value(end-1:end),'µm')
                app.MI.CGcam.CG = CrossGrating(Gamma = str2double(app.CrossGratingDropDown.Value(1:end-2))*1e-6,lambda0 = 550e-9);
            else
                app.MI.CGcam.CG = CrossGrating(app.CrossGratingDropDown.Value);
            end

        end

        function buildWindowsHandles(app)
            % Function that builds the hfig, hax, him properties from the
            % list of button tags ('raw','T','W',...))
            childrens = app.GridLayout_windowsButtons.Children;
            Nt = numel(childrens);
        
            for k = 1:Nt
                element = childrens(k);
                if isa(element, 'matlab.ui.control.StateButton')
                    if ~isempty(element.Tag)
                        app.hfigList.(element.Tag) = matlab.ui.Figure.empty();
                        app.haxList.(element.Tag) =  matlab.graphics.axis.Axes.empty();
                        app.himList.(element.Tag) =  matlab.graphics.primitive.Image.empty();
                    end
                end
            end
        end
            
        function toggleFigure(app,tag,val) % build or delete the popup figures
            
            app.([tag,'Button']).Value = val;
            if val % Open the figure
                    [hf,ha,hi] = buildCustomFigure(tag,'Nx',app.Nx,'Ny',app.Ny,'Position',app.nPos,'enableClose',false);
                    app.nPos =  app.nPos+1;
                    app.hfigList.(tag) = hf;
                    app.haxList.(tag) = ha;
                    app.himList.(tag) = hi;
                    app.updateImages();
    
            else   % Close the figure
                app.([tag,'Button']).Value = 0;
                if isvalid(app.hfigList.(tag))
                    close(app.hfigList.(tag));
                end
                delete(app.hfigList.(tag))
                app.hfigList.(tag) = matlab.ui.Figure.empty();
                app.himList.(tag) = matlab.graphics.primitive.Image.empty();
                app.haxList.(tag) = matlab.graphics.axis.Axes.empty();

            end

            if ~any(app.status_hfigList) % if all the windows are closed
                app.stopLive()
            end
            
            app.changeButtonColor(app.([tag,'Button']));
        end

        function updateCamera(app)
            camName = app.cameraDropDown.Value;

            switch camName
                case {'Zyla5.5','Orca Flash','Panda','OE #17-816','2-color','4-polar','Simu'}
                    app.cameraDropDownLabel.Text = 'Camera';
                    app.enableCameraParameters("on");
                    cam = Camera(strrep(camName, ' ', ''));
                    app.MI.CGcam.Camera = cam.makeAlive();

                case {'sC8-830','sC8-944'}
                    app.MI.CGcam = CGcamera(camName);
                    app.MI.CGcam.Camera = app.MI.CGcam.Camera.makeAlive;
                    app.cameraDropDownLabel.Text = 'CGcamera';  % 'La chaîne contient d'autres caractères que des nombres
                    app.CrossGratingDropDown.Value = 'sC8';
                    app.RelayLensEditField.Value = [];
                    app.CGdistanceEditField.Value = app.MI.CGcam.CGpos*1e3;
                    app.MI2panel()
                    app.enableCameraParameters("off");
            end
            %app.roi_x = 1:app.MI.CGcam.Camera.Nx;
            %app.roi_y = 1:app.MI.CGcam.Camera.Ny;
        end
        
        function startLive(app)
            app.LiveButton.Value = 1;
            app.LiveButton.Icon = 'STOP.svg';
            app.imageDimensionsDropDown.Enable = false;

            app.liveOn = 1;
            
            app.Cam.startAcquisition(app,'Itf')
            app.Cam.stopAcquisition(app)

            app.stopLive()

            
        end

        function stopLive(app)
            app.lampState('On')
            app.LiveButton.Value = 0;
            app.LiveButton.Icon = 'LIVE.svg';
            app.liveOn = 0;
            app.HzEditField.Value = double.empty();
            app.imageDimensionsDropDown.Enable = true;

        end

        function updateIL(app)
            app.IL.lambda = app.lambdaEditField.Value;
        end
        
        function clearImages(app)
            if ~isempty(app.himList.raw)
                app.himList.raw.CData  = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.T)
                app.himList.T.CData    = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.W)
                app.himList.W.CData    = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.dW)
                app.himList.dW.CData   = zeros(app.Ny,app.Nx);
            end
            if ~isempty(app.himList.mass)
                app.himList.mass.CData = zeros(app.Ny,app.Nx);
            end
            
            if ~isempty(app.himList.PDCM)
                app.himList.PDCM.CData = zeros(app.Ny,app.Nx);
            end

        end
        
        function clearRef(app)
            app.Ref0 = [];
            app.Itf.clearTF()            
        end
        
        function cropDefinition(app)
            hax = copy(gca);
            h = figure;
            hax.Parent=h;
            colorbar('off')
            hax.Position = [10,10,400,400];
            h.Position = [632 174 417 459];
            h.Color = [0.9 0.9 0.9];
            hax.Title.String = 'toto';
            hax.Title.Color = [1 1 1];
            hax.XColor = [0 0 0];
            hax.YColor = [0 0 0];
            hax.Children.XData = 1:app.Itf.Nx;
            hax.Children.YData = 1:app.Itf.Ny;

            app.roi = drawrectangle(hax);
%            addlistener(roi,'ROIMoved',@(src, evt) roiMoved(app));
            %roi.DeleteFcn = @roiDeleted;
            
            % function roiMoved(src, evt, app)
            %     newCrop = round(evt.CurrentPosition);
            %     %ha = src.Parent;
            %     app.roi = newCrop;
            % end

        end
        
        function saveCurrentImage(app)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE2.svg');

            if ~all(app.Itf.ROI == app.Ref.ROI) % if the ROI are not the same
                app.savedFileNameEditField.Value = 'error: not the same Ref/Itf ROIs';
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVEerror.svg');

                return
            end

            if isnat(app.Itf.timeStamp)
                app.savedFileNameEditField.Value = 'error: no Itf acquired';
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVEerror.svg');

                return
            end                

            folder = app.saveFolderEditField.Value;

            if isempty(folder)
                folder = pwd;
            else
                folder = app.saveFolderEditField.Value;
            end

            if isnat(app.Ref.timeStamp)
                app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVEerror.svg');
                app.savedFileNameEditField.Value = 'warning: no Ref saved';
                RefFileName = '';
                pause(1)
            else
                RefFileName = createFileName('REF', folder, app.Ref.timeStamp,app.Ref.ROI);
                saveTiffMeta(folder+"/"+RefFileName, app.Ref, app.MI, "lambda", app.IL.lambda, "ROI",app.Ref.ROI)

            end
            

            ItfFileName = createFileName(app.savePrefixEditField.Value, folder);
            saveTiffMeta(folder+"/"+ItfFileName, app.Itf, app.MI, "lambda", app.IL.lambda, "ROI",app.Itf.ROI,"RefFilename",RefFileName)
            app.savedFileNameEditField.Value = ItfFileName;

            app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE.svg');

            
        end
    end


    methods(Access = public)

        function updateImages(app)
            if app.flattenButton.Value
                symD4 = @(img) ZernikeRemoval(applySymmetryD4_bis(img,app.D4Transform),2,0);
            else
                symD4 = @(img) applySymmetryD4_bis(img,app.D4Transform);
            end
            if ~isempty(app.himList.raw)
                if ~isempty(app.Itf.Itf)
%                    app.himList.raw.CData(app.roi_y,app.roi_x)  = symD4( app.Itf.Itf ); % To uncomment when implementing the peephole. It is complicated because the roi depends on the Itf and the W that is 3 times smaller. 
                    app.himList.raw.CData  = symD4( app.Itf.Itf );
                end
            end
            if ~isempty(app.himList.T)
%                app.himList.T.CData(app.roi_y,app.roi_x)    = symD4( app.IM.T );
                app.himList.T.CData    = symD4( app.IM.T );
            end
            if ~isempty(app.himList.W)
                app.himList.W.CData    = symD4( app.IM.OPDnm );
            end
            if ~isempty(app.himList.dW)
                app.himList.dW.CData   = symD4( app.IM.DWx.^2 + app.IM.DWy.^2 );
            end
            if ~isempty(app.himList.mass)
                app.himList.mass.CData = symD4( 5.56*1e-3 * app.IM.OPDnm );
            end
            
            if ~isempty(app.himList.PDCM)
                dxDWy  = imgradientxy(app.IM.DWy);
                [~, dyDWx] = imgradientxy(app.IM.DWx);
                app.himList.PDCM.CData = symD4( imgaussfilt(dxDWy-dyDWx,3) );
            end
            
            if ~isempty(app.himList.Rytov)
                app.himList.Rytov.CData    = symD4( app.IM.Rytov );
            end
            
            
            drawnow            
        end
    end
    
    methods % set and get methods

        function val = get.Itf0(app)
            if isempty(app.roi)
                val = app.Itf.Itf;
            else
                [~,~,val] = app.Itf.crop('roi',app.roi.Position);
            end
        end
        function set.Itf0(app,val)
            app.Itf.Itf0 = val;
        end

        function val = get.Ref0(app)
            val = app.Ref.Itf;
        end
        function set.Ref0(app,val)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if isempty(val)
                app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF0.svg');
            else
                app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF.svg');
            end
            app.Ref.Itf0 = val;
        end

        function val = get.W0(app)
            val = app.IM.OPDnm;
        end
        function set.W0(app,val)
            app.IM.OPD0 = val;
        end

        function val = get.T0(app)
            val = app.IM.T;
        end
        function set.T0(app,val)
            app.IM.T0 = val;
        end

        function val = get.DWx0(app)
            val = app.IM.DWx0;
        end
        function set.DWx0(app,val)
            app.IM.DWx0 = val;
        end

        function val = get.DWy0(app)
            val = app.IM.DWy0;
        end
        function set.DWy0(app,val)
            app.IM.DWy0 = val;
        end

        function val = get.Cam(app)
            val = app.MI.CGcam.Camera;
        end

        function val = get.Nx(app)
            val = app.Cam.width;
        end

        function val = get.Ny(app)
            val = app.Cam.height;
        end

        function val = get.NavgLive(app)
            val = app.liveAvgEditField.Value;
        end

        function val = get.NavgRef(app)
            val = app.refAvgEditField.Value;
        end

        function val = get.NavgSnap(app)
            val = app.snapAvgEditField.Value;
        end

        function val = get.expTime(app)
            val = app.expTimeEditField.Value;
        end

        function set.expTime(app,val)
            if val ~= app.snapAvgEditField.Value
                app.expTimeEditField.Value = val;
            end
            app.MI.CGcam.Camera.expTime = val;
        end

        function val2 = lampState(app,val)
            % app.lampState("Off")
            % val = app.lampState;
            arguments
                app
                val {mustBeMember(val,{'On','Off','Wait','Error','Running','on','off','wait','running','error',''})} = ''% state of the camera lamp button
            end
            if ~strcmp(val,'')
                val(1) = upper(val(1)); % uppercase the first letter
                app.Lamp.BackgroundColor = app.("lampColor"+val);
                app.Lamp.UserData.state = val;
            elseif nargout
                val2 = app.Lamp.UserData.state;
            end
            drawnow
        end

        function results = get.status_hfigList(app)
            % returns an array of logical values saying which windows are open.
            cellValues = struct2cell(app.hfigList);
            isEmptyLogical = cellfun(@isempty, cellValues);
            results = ~isEmptyLogical;
        end

        function val = imList(app,tag)
            switch tag
                case 'raw'
                    val = app.Itf(1).Itf;
                case 'W'
                    val = app.IM.OPDnm;
                case 'T'
                    val = app.IM.T;
                case 'dW'
                    val = app.IM.D2Wnorm;
                case 'mass'
                    val = app.IM.dmd;
                case 'PDCM'
                    val = app.IM.PDCM;
                case 'Rytov'
                    val = app.IM.Rytov;
                otherwise
                    error('tag not defined')
            end
        end


    end

    methods(Static)
       function setupAndorSDK3()
            % ge the root directory of Matlab
            matlabRootPath = matlabroot;
        
            % Detection of the OS
            if ispc
                % bBuild the path for Windows
                sdkPath = fullfile(matlabRootPath, 'toolbox', 'AndorSDK3');
            elseif ismac
                % Build the path for Mac
                sdkPath = fullfile(matlabRootPath, 'toolbox', 'AndorSDK3');
            else
                error('unsupported OS');
            end
        
            % add the path
            addpath(genpath(sdkPath));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.IM = ImageQLSI();
                        
            %% Define the app (figure) position (middle top)
            screenSize = get(0, 'ScreenSize');

            appWidth = app.UIFigure.Position(3);
            appHeight = app.UIFigure.Position(4);

            % Calculate the x position to center horizontally (optional)
            xPos = (screenSize(3) - appWidth) / 2;
            
            % Calculate the y position to align at the top of the screen.
            % Since the coordinate system starts at the bottom left,
            % we set y such that yPos + appHeight = screenSize(4)
            yPos = screenSize(4) - appHeight;

            app.UIFigure.Position(1:2) = [xPos,yPos];
            
            %% Define the UserData of the upper-left state button
            app.Lamp.UserData.colorOn    = app.lampColorOn;
            app.Lamp.UserData.colorOff   = app.lampColorOff;
            app.Lamp.UserData.colorWait  = app.lampColorWait;
            app.Lamp.UserData.colorError = app.lampColorError;
            app.Lamp.UserData.colorRunning  = app.lampColorRunning;

            app.lampState('Off')

            %% Creating the list of butttons, hlistt
            
            hList = app.GridLayout_panelButtons.Children; % list of all the image type buttons
            for ii = 1:numel(hList)
                app.changeButtonColor(hList(ii));
            end

            %% Creating the app Microscope
            % Creating a blank Microscope
            app.MI = Microscope();
            app.MI.CGcam.CG = CrossGrating();
            app.panel2MI()
            app.RelayLensEditField.AllowEmpty = 'on';
            app.HzEditField.AllowEmpty = 'on';
            app.RelayLensEditField.Value = [];

            app.IL = Illumination(app.lambdaEditField.Value,Medium(1.33,1.5));
            app.IM.Illumination = app.IL;
            % update the Microscope from the initial panel
            app.updateCamera()
            app.imageDimensionsDropDown.Items = app.Cam.imageDimensionsList;
            app.panel2MI()
            
            app.Itf     = Interfero(zeros(app.Cam.Ny,app.Cam.Nx),app.MI);
            app.Itf.Ref = Interfero(zeros(app.Cam.Ny,app.Cam.Nx),app.MI);
            app.Itf.Ref.Itf0 = [];
            app.Ref = app.Itf.Ref;
            
            % assign tags to figure handles in app.hfig
            app.buildWindowsHandles();


        end

        % Value changed function: PDCMButton, RytovButton, TButton, 
        % ...and 4 other components
        function toggleImageWindow(app, event)
            % open or close the corresponding figure

            app.toggleFigure(event.Source.Tag,event.Source.Value)

        end

        % Value changed function: CGdistanceEditField, 
        % ...and 5 other components
        function microscopeValueChanged(app, event)
            value = app.microscopeTLDropDown.Value;
            if ~isnan(str2double(value))
                app.microscopeTLDropDownLabel.Text = 'f_TL';
                %str2double(value)  % La chaîne ne contient que des nombres
            else
                app.microscopeTLDropDownLabel.Text = 'Microscope';  % 'La chaîne contient d'autres caractères que des nombres
            end

            app.panel2MI()
        end

        % Button pushed function: closeallButton
        function closeallButtonValueChanged(app, event)
            tags = fields(app.hfigList);
            Na = numel(tags);
            for ia = 1:Na
                if ~isempty(app.hfigList.(tags{ia}))
                    app.toggleFigure(tags{ia},0)
                end
            end
            app.nPos=0;
            app.stopLive()
            
        end

        % Button pushed function: Lamp
        function LampButtonPushed(app, event)
            state = app.Lamp.UserData.state; % 'on' or 'off'
            app.lampState('wait')
            switch state
                case {'on','On'}
                    app.MI.CGcam.Camera.switchCamera('off')  %
                    app.lampState('off')
                case {'off','Off'}
                    rc = app.MI.CGcam.Camera.switchCamera('on');  %
                    if rc == 0
                        app.lampState('on')
                    else
                        app.lampState('error')
                    end
                case {'error','Error'}
                    app.MI.CGcam.Camera.switchCamera('off')  %
                    app.lampState('off')
                otherwise %wait
                    disp('wait')
            end

        end

        % Value changed function: cameraDropDown
        function cameraDropDownValueChanged(app, event)
            app.lampState('Off') % Just to make sure it is off.

            app.updateCamera()
        end

        % Callback function
        function simuButtonValueChanged(app, event)
            value = app.simuButton.Value;
            switch value
                case 1 % on
                    event.Source.BackgroundColor = app.simuColorOn;
                case 0 % off
                    event.Source.BackgroundColor = app.simuColorOff;
            end
        end

        % Value changed function: burstCheckBox
        function burstCheckBoxValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.burstCheckBox.Value
                app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'BURST.svg');
            else
                app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'SNAP.svg');
            end                
        end

        % Value changed function: lambdaEditField
        function lambdaEditFieldValueChanged(app, event)
            app.updateIL()
            
        end

        % Value changed function: imageDimensionsDropDown
        function imageDimensionsDropDownValueChanged(app, event)
            value = app.imageDimensionsDropDown.Value;
            app.Cam.setImageDimensions(value)
            %app.roi_x = 1:app.Cam.width;
            %app.roi_y = 1:app.Cam.height;
            app.clearImages()
            app.clearRef()

        end

        % Value changed function: LiveButton
        function LiveButtonValueChanged(app, event)
            app.Cam.setMode('Continuous', 'Software',"expTime",app.expTimeEditField.Value,"Nim",app.liveAvgEditField.Value)
            if app.LiveButton.Value                
                app.startLive()
            else
                app.stopLive()
            end
        end

        % Button pushed function: RefButton
        function RefButtonPushed(app, event)
            app.Cam.stopAcquisition(app)
            app.Cam.setMode('Fixed', 'Software',"expTime",app.expTimeEditField.Value,"Nim",app.refAvgEditField.Value)
            app.Cam.startAcquisition(app,'Ref');
            app.Cam.stopAcquisition(app)

        end

        % Button pushed function: SnapButton
        function SnapButtonPushed(app, event)
            app.Cam.stopAcquisition(app)
            app.Cam.setMode('Fixed', 'Software',"expTime",app.expTimeEditField.Value,"Nim",app.snapAvgEditField.Value)
            app.Cam.startAcquisition(app,'Itf');
            app.Cam.stopAcquisition(app)

            if app.autosaveCheckBox.Value
                app.saveCurrentImage()
                pause(0.5)
            else
                app.liveOn = 0;
            end


        end

        % Value changed function: HR_Button
        function HR_ButtonValueChanged(app, event)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            if app.HR_Button.Value
                app.HR_Button.Icon = fullfile(pathToMLAPP, 'icons', 'HR2.svg');
            else
                app.HR_Button.Icon = fullfile(pathToMLAPP, 'icons', 'HR.svg');
            end
        end

        % Button pushed function: saveFolderButton
        function saveFolderButtonPushed(app, event)
            app.UIFigure.Visible='off' ;
            app.saveFolderEditField.Value = uigetdir();
            app.UIFigure.Visible='on';

        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            app.saveCurrentImage()
        end

        % Value changed function: expTimeEditField
        function expTimeEditFieldValueChanged(app, event)
            value = app.expTimeEditField.Value;
            app.Cam.expTime = value;
        end

        % Button pushed function: HmirrorButton, VmirrorButton, rot90Button
        function applySymmetryPushed(app, event)
            app.D4Transform =  [app.D4Transform event.Source.Tag];
        end

        % Button pushed function: cancelSymmetryButton
        function cancelSymmetryButtonPushed(app, event)
            app.D4Transform = '';
        end

        % Callback function
        function peepholeButtonPushed(app, event)
            
                roi = drawrectangle();
                addlistener(roi,'MovingROI',@(src, evt)roiMoved(evt, app));
                roi.DeleteFcn = @(src, evt)roiDeleted(app);
                crops = round(roi.Position);
                drawnow
                %app.roi_x = crops(1) : crops(1)+crops(3);
                %app.roi_y = crops(2) : crops(2)+crops(4);            

                function roiMoved(evt, app)
                    newCrop = round(evt.CurrentPosition);
                    %app.roi_x = newCrop(1) : newCrop(1)+newCrop(3);
                    %app.roi_y = newCrop(2) : newCrop(2)+newCrop(4);
                end
    
                function roiDeleted(app)
                    %app.roi_x = 1:app.Cam.Nx;
                    %app.roi_y = 1:app.Cam.Ny;
                end


        end

        % Button pushed function: cropButton
        function cropButtonPushed(app, event)
            app.cropDefinition()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'PhaseLAB - Live imaging';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'favicon.png');
            app.GridLayout_main = uigridlayout(app.UIFigure);
            app.GridLayout_setup = uigridlayout(app.GridLayout_main);
            app.MobjEditFieldLabel = uilabel(app.GridLayout_setup);
            app.MobjEditFieldLabel.Text = 'Mobj';
            app.MobjEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.MobjEditField.Limits = [1 Inf];
            app.MobjEditField.RoundFractionalValues = 'on';
            app.MobjEditField.ValueDisplayFormat = '%6.0f';
            app.MobjEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.MobjEditField.Value = 60;
            app.lambdaLabel = uilabel(app.GridLayout_setup);
            app.lambdaLabel.Text = 'λ';
            app.lambdaEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.lambdaEditField.Limits = [100 Inf];
            app.lambdaEditField.ValueChangedFcn = createCallbackFcn(app, @lambdaEditFieldValueChanged, true);
            app.lambdaEditField.Value = 625;
            app.objBrandDropDown = uidropdown(app.GridLayout_setup);
            app.objBrandDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon'};
            app.objBrandDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.objBrandDropDown.Value = 'Olympus';
            app.microscopeTLDropDownLabel = uilabel(app.GridLayout_setup);
            app.microscopeTLDropDownLabel.Text = 'microscope/TL';
            app.microscopeTLDropDown = uidropdown(app.GridLayout_setup);
            app.microscopeTLDropDown.Items = {'Olympus', 'Zeiss', 'Leica', 'Nikon', '165', '180', '200'};
            app.microscopeTLDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.microscopeTLDropDown.Value = 'Olympus';
            app.cameraDropDownLabel = uilabel(app.GridLayout_setup);
            app.cameraDropDownLabel.Text = 'camera';
            app.cameraDropDown = uidropdown(app.GridLayout_setup);
            app.cameraDropDown.Items = {'Simu', 'Zyla5.5', 'Orca Flash', 'Panda', 'Pixelink', '2-color', '4-polar', 'sC8-830', 'sC8-944'};
            app.cameraDropDown.ValueChangedFcn = createCallbackFcn(app, @cameraDropDownValueChanged, true);
            app.cameraDropDown.Value = 'Simu';
            app.CrossGratingDropDownLabel = uilabel(app.GridLayout_setup);
            app.CrossGratingDropDownLabel.Text = '2D Grating';
            app.CrossGratingDropDown = uidropdown(app.GridLayout_setup);
            app.CrossGratingDropDown.Items = {'F02', 'F03', 'F03b', 'F04', 'P02', 'P04', 'sC8', 'Sid4Element', '39 µm', '35.1 µm', '22.5 µm', '13 µm'};
            app.CrossGratingDropDown.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CrossGratingDropDown.Value = 'F02';
            app.RelayLensEditFieldLabel = uilabel(app.GridLayout_setup);
            app.RelayLensEditFieldLabel.Text = 'Relay Lens';
            app.RelayLensEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.RelayLensEditField.Limits = [0 Inf];
            app.RelayLensEditField.ValueDisplayFormat = '%2.2f';
            app.RelayLensEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.RelayLensEditField.Value = 1.11;
            app.nmLabel = uilabel(app.GridLayout_setup);
            app.nmLabel.Text = 'nm';
            app.CGdistanceEditField = uieditfield(app.GridLayout_setup, 'numeric');
            app.CGdistanceEditField.ValueDisplayFormat = '%2.3f';
            app.CGdistanceEditField.ValueChangedFcn = createCallbackFcn(app, @microscopeValueChanged, true);
            app.CGdistanceEditField.Value = 0.8;
            app.mmLabel = uilabel(app.GridLayout_setup);
            app.mmLabel.Text = 'mm';
            app.Lamp = uibutton(app.GridLayout_setup, 'push');
            app.Lamp.ButtonPushedFcn = createCallbackFcn(app, @LampButtonPushed, true);
            app.Lamp.Text = '';
            app.Image = uiimage(app.GridLayout_main);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'banniere.svg');
            app.GridLayout = uigridlayout(app.GridLayout_main);
            app.GridLayout_Imaging_3 = uigridlayout(app.GridLayout);
            app.LiveButton = uibutton(app.GridLayout_Imaging_3, 'state');
            app.LiveButton.ValueChangedFcn = createCallbackFcn(app, @LiveButtonValueChanged, true);
            app.LiveButton.Icon = fullfile(pathToMLAPP, 'icons', 'LIVE.svg');
            app.LiveButton.Text = '';
            app.RefButton = uibutton(app.GridLayout_Imaging_3, 'push');
            app.RefButton.ButtonPushedFcn = createCallbackFcn(app, @RefButtonPushed, true);
            app.RefButton.Icon = fullfile(pathToMLAPP, 'icons', 'REF0.svg');
            app.RefButton.Text = '';
            app.SnapButton = uibutton(app.GridLayout_Imaging_3, 'push');
            app.SnapButton.ButtonPushedFcn = createCallbackFcn(app, @SnapButtonPushed, true);
            app.SnapButton.Icon = fullfile(pathToMLAPP, 'icons', 'SNAP.svg');
            app.SnapButton.Text = '';
            app.snapAvgEditField = uieditfield(app.GridLayout_Imaging_3, 'numeric');
            app.snapAvgEditField.Limits = [1 Inf];
            app.snapAvgEditField.RoundFractionalValues = 'on';
            app.snapAvgEditField.ValueDisplayFormat = '%11.0f';
            app.snapAvgEditField.Value = 30;
            app.burstCheckBox = uicheckbox(app.GridLayout_Imaging_3);
            app.burstCheckBox.ValueChangedFcn = createCallbackFcn(app, @burstCheckBoxValueChanged, true);
            app.burstCheckBox.Text = 'burst';
            app.refAvgEditField = uieditfield(app.GridLayout_Imaging_3, 'numeric');
            app.refAvgEditField.Limits = [1 Inf];
            app.refAvgEditField.RoundFractionalValues = 'on';
            app.refAvgEditField.ValueDisplayFormat = '%11.0f';
            app.refAvgEditField.Value = 30;
            app.liveAvgEditField = uieditfield(app.GridLayout_Imaging_3, 'numeric');
            app.liveAvgEditField.Limits = [1 Inf];
            app.liveAvgEditField.RoundFractionalValues = 'on';
            app.liveAvgEditField.ValueDisplayFormat = '%11.0f';
            app.liveAvgEditField.Value = 1;
            app.GridLayout_Imaging_4 = uigridlayout(app.GridLayout);
            app.saveButton = uibutton(app.GridLayout_Imaging_4, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Icon = fullfile(pathToMLAPP, 'icons', 'SAVE.svg');
            app.saveButton.Text = '';
            app.GridLayout_savePrefix = uigridlayout(app.GridLayout_Imaging_4);
            app.savedFileNameEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.autosaveCheckBox = uicheckbox(app.GridLayout_savePrefix);
            app.autosaveCheckBox.Text = 'autosave';
            app.savePrefixEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.savePrefixEditField.Value = 'ITF';
            app.saveFolderButton = uibutton(app.GridLayout_savePrefix, 'push');
            app.saveFolderButton.ButtonPushedFcn = createCallbackFcn(app, @saveFolderButtonPushed, true);
            app.saveFolderButton.Icon = fullfile(pathToMLAPP, 'icons', 'folder.svg');
            app.saveFolderButton.Text = '';
            app.saveFolderEditField = uieditfield(app.GridLayout_savePrefix, 'text');
            app.saveFolderEditField.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout_Imaging_4);
            app.loadFileButton = uibutton(app.GridLayout3, 'push');
            app.loadFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'import.svg');
            app.loadFileButton.Tooltip = {'import image'};
            app.loadFileButton.Text = '';
            app.timeLapseFileButton = uibutton(app.GridLayout3, 'push');
            app.timeLapseFileButton.Icon = fullfile(pathToMLAPP, 'icons', 'MOVIE.svg');
            app.timeLapseFileButton.Tooltip = {'acqiure time lapse'};
            app.timeLapseFileButton.Text = '';
            app.GridLayout_Imaging_5 = uigridlayout(app.GridLayout);
            app.GridLayout_tools = uigridlayout(app.GridLayout_Imaging_5);
            app.VmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.VmirrorButton.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.VmirrorButton.Tag = 'V';
            app.VmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorV.svg');
            app.VmirrorButton.Tooltip = {'vertical mirror'};
            app.VmirrorButton.Text = '';
            app.HmirrorButton = uibutton(app.GridLayout_tools, 'push');
            app.HmirrorButton.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.HmirrorButton.Tag = 'H';
            app.HmirrorButton.Icon = fullfile(pathToMLAPP, 'icons', 'mirrorH.svg');
            app.HmirrorButton.Tooltip = {'Horizontal mirror'};
            app.HmirrorButton.Text = '';
            app.rot90Button = uibutton(app.GridLayout_tools, 'push');
            app.rot90Button.ButtonPushedFcn = createCallbackFcn(app, @applySymmetryPushed, true);
            app.rot90Button.Tag = 'R';
            app.rot90Button.Icon = fullfile(pathToMLAPP, 'icons', 'rot90.svg');
            app.rot90Button.Tooltip = {'Clockwise rotation'};
            app.rot90Button.Text = '';
            app.cancelSymmetryButton = uibutton(app.GridLayout_tools, 'push');
            app.cancelSymmetryButton.ButtonPushedFcn = createCallbackFcn(app, @cancelSymmetryButtonPushed, true);
            app.cancelSymmetryButton.Icon = fullfile(pathToMLAPP, 'icons', 'erase2.svg');
            app.cancelSymmetryButton.Tooltip = {'Back to native orientation'};
            app.cancelSymmetryButton.Text = '';
            app.flattenButton = uibutton(app.GridLayout_tools, 'state');
            app.flattenButton.Tooltip = {'Flatten'};
            app.flattenButton.Icon = fullfile(pathToMLAPP, 'icons', 'flatten.svg');
            app.flattenButton.Text = '';
            app.tauLabel = uilabel(app.GridLayout_tools);
            app.tauLabel.Text = 'τ';
            app.expTimeEditField = uieditfield(app.GridLayout_tools, 'numeric');
            app.expTimeEditField.Limits = [1 Inf];
            app.expTimeEditField.ValueChangedFcn = createCallbackFcn(app, @expTimeEditFieldValueChanged, true);
            app.expTimeEditField.Value = 80;
            app.msLabel = uilabel(app.GridLayout_tools);
            app.msLabel.Text = 'ms';
            app.imageDimensionsDropDown = uidropdown(app.GridLayout_tools);
            app.imageDimensionsDropDown.Items = {'2560 x 2160', '2064 x 2048', '1920 x 1080', '1392 x 1040', '528 x 512', '240 x 256', '144 x 128', '2560 x 304', 'custom'};
            app.imageDimensionsDropDown.ValueChangedFcn = createCallbackFcn(app, @imageDimensionsDropDownValueChanged, true);
            app.imageDimensionsDropDown.Value = '2560 x 2160';
            app.pxLabel = uilabel(app.GridLayout_tools);
            app.pxLabel.Text = 'px';
            app.areaButton = uibutton(app.GridLayout_tools, 'push');
            app.areaButton.Icon = fullfile(pathToMLAPP, 'icons', 'areaButton.svg');
            app.areaButton.Tooltip = {'Average value over an area'};
            app.areaButton.Text = '';
            app.pxValueButton = uibutton(app.GridLayout_tools, 'push');
            app.pxValueButton.Icon = fullfile(pathToMLAPP, 'icons', 'pixel.svg');
            app.pxValueButton.Tooltip = {'Pixel value'};
            app.pxValueButton.Text = '';
            app.radialAvgButton = uibutton(app.GridLayout_tools, 'push');
            app.radialAvgButton.Icon = fullfile(pathToMLAPP, 'icons', 'Rcrosscut.svg');
            app.radialAvgButton.Text = '';
            app.cropButton = uibutton(app.GridLayout_tools, 'push');
            app.cropButton.ButtonPushedFcn = createCallbackFcn(app, @cropButtonPushed, true);
            app.cropButton.Icon = fullfile(pathToMLAPP, 'icons', 'areaButton.svg');
            app.cropButton.Tooltip = {'Crop the field of view'};
            app.cropButton.Text = '';
            app.HR_Button = uibutton(app.GridLayout_tools, 'state');
            app.HR_Button.ValueChangedFcn = createCallbackFcn(app, @HR_ButtonValueChanged, true);
            app.HR_Button.Icon = fullfile(pathToMLAPP, 'icons', 'HR.svg');
            app.HR_Button.Text = '';
            app.GridLayout2 = uigridlayout(app.GridLayout_main);
            app.GridLayout_windowsButtons = uigridlayout(app.GridLayout2);
            app.rawButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.rawButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.rawButton.Tag = 'raw';
            app.rawButton.Text = 'raw';
            app.TButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.TButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.TButton.Tag = 'T';
            app.TButton.Text = 'T';
            app.WButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.WButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.WButton.Tag = 'W';
            app.WButton.Text = 'W';
            app.dWButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.dWButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.dWButton.Tag = 'dW';
            app.dWButton.Text = '∇ W';
            app.massButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.massButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.massButton.Tag = 'mass';
            app.massButton.Text = 'mass';
            app.PDCMButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.PDCMButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.PDCMButton.Tag = 'PDCM';
            app.PDCMButton.Text = 'PDCM';
            app.RytovButton = uibutton(app.GridLayout_windowsButtons, 'state');
            app.RytovButton.ValueChangedFcn = createCallbackFcn(app, @toggleImageWindow, true);
            app.RytovButton.Tag = 'Rytov';
            app.RytovButton.Text = 'Rytov';
            app.canals2Button = uibutton(app.GridLayout_windowsButtons, 'state');
            app.canals2Button.Tag = 'canals2';
            app.canals2Button.Text = '2-canals';
            app.canals4Button = uibutton(app.GridLayout_windowsButtons, 'state');
            app.canals4Button.Tag = 'canals4';
            app.canals4Button.Text = '4-canals';
            app.closeallButton = uibutton(app.GridLayout_windowsButtons, 'push');
            app.closeallButton.ButtonPushedFcn = createCallbackFcn(app, @closeallButtonValueChanged, true);
            app.closeallButton.Text = 'close all';
            app.GridLayout_panelButtons = uigridlayout(app.GridLayout2);
            app.setupButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.setupButton.Text = 'setup';
            app.zcontrolButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.zcontrolButton.Text = 'z control';
            app.controlButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.controlButton.Text = 'λ control';
            app.PhaseLABButton = uibutton(app.GridLayout_panelButtons, 'state');
            app.PhaseLABButton.Text = 'PhaseLAB';
            app.GridLayout_frameRate = uigridlayout(app.GridLayout2);
            app.HzEditFieldLabel = uilabel(app.GridLayout_frameRate);
            app.HzEditFieldLabel.Text = 'Hz';
            app.HzEditField = uieditfield(app.GridLayout_frameRate, 'numeric');
            app.HzEditField.Limits = [0 Inf];
            app.HzEditField.ValueDisplayFormat = '%4.2g';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LIVE

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end