classdef shutterControlWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        Image                  matlab.ui.control.Image
        GridLayout_Position    matlab.ui.container.GridLayout
        ConnexionButton        matlab.ui.control.Button
        Lamp_Connected         matlab.ui.control.Lamp
        Switch                 matlab.ui.control.Switch
        Lamp_Switch            matlab.ui.control.Lamp
        portlineDropDown       matlab.ui.control.DropDown
        portlineDropDownLabel  matlab.ui.control.Label
        DevDropDown            matlab.ui.control.DropDown
        DevDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        dq
        SH
    end


    methods (Access = private)
        
        function updateSH(app)
            devID = app.DevDropDown.Value;
            portLine = app.portlineDropDown.Value;
            app.SH.deviceID = devID;
            app.SH.portLine = portLine;
        end
        
        function releaseSwitch(app, val)
            arguments
                app
                val logical
            end
            app.Switch.Enable = val;            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.SH = Shutter();

            app.dq = daqlist("ni");

            if isempty(app.dq)
                app.DevDropDown.Items = {'No NI device found'};
                app.DevDropDown.Enable = 'off';
            else
                app.DevDropDown.Items = cellstr(app.dq.DeviceID);
                app.DevDropDown.Enable = 'on';
            end

            app.updateSH()
  
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.SH.delete()
            delete(app)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value
                app.SH.open()
                app.Lamp_Switch.Color = [0 1 0];
            else
                app.SH_close()
                app.Lamp_Switch.Color = [0.2 0.2 0.2];
            end
        end

        % Value changed function: DevDropDown
        function DevDropDownValueChanged(app, event)
            app.updateSH()
        end

        % Value changed function: portlineDropDown
        function portlineDropDownValueChanged(app, event)
            app.updateSH()
        end

        % Button pushed function: ConnexionButton
        function ConnexionButtonPushed(app, event)
            try
                app.SH.connect()
                app.Lamp_Connected.Color = [0.2 0.8 0.3];
                app.releaseSwitch()
            catch
                app.Lamp_Connected.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'x, y stage control';
            app.UIFigure.Icon = fullfile(pathToMLAPP, 'icons', 'go.svg');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout_Position = uigridlayout(app.GridLayout);
            app.DevDropDownLabel = uilabel(app.GridLayout_Position);
            app.DevDropDownLabel.Text = 'Dev';
            app.DevDropDown = uidropdown(app.GridLayout_Position);
            app.DevDropDown.Items = {'None'};
            app.DevDropDown.ValueChangedFcn = createCallbackFcn(app, @DevDropDownValueChanged, true);
            app.DevDropDown.Value = 'None';
            app.portlineDropDownLabel = uilabel(app.GridLayout_Position);
            app.portlineDropDownLabel.Text = 'port/line';
            app.portlineDropDown = uidropdown(app.GridLayout_Position);
            app.portlineDropDown.Items = {'None'};
            app.portlineDropDown.ValueChangedFcn = createCallbackFcn(app, @portlineDropDownValueChanged, true);
            app.portlineDropDown.Value = 'None';
            app.Lamp_Switch = uilamp(app.GridLayout_Position);
            app.Switch = uiswitch(app.GridLayout_Position, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Enable = 'off';
            app.Lamp_Connected = uilamp(app.GridLayout_Position);
            app.ConnexionButton = uibutton(app.GridLayout_Position, 'push');
            app.ConnexionButton.ButtonPushedFcn = createCallbackFcn(app, @ConnexionButtonPushed, true);
            app.ConnexionButton.Text = 'Connexion';
            app.Image = uiimage(app.GridLayout);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'icons', 'shutterControlTitle.svg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = shutterControlWindow

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end